classdef TRA_THONG_SO_NUOC_V1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermodynamicpropertiesofWaterandSteamUIFigure  matlab.ui.Figure
        START                 matlab.ui.control.Button
        xuat                  matlab.ui.control.EditField
        can_tim               matlab.ui.control.DropDown
        PressurePbarLabel     matlab.ui.control.Label
        p_v                   matlab.ui.control.EditField
        TemperatureTCLabel    matlab.ui.control.Label
        t_v                   matlab.ui.control.EditField
        EntropySkJkgCLabel    matlab.ui.control.Label
        s_v                   matlab.ui.control.EditField
        EnthalpyHkJkgLabel    matlab.ui.control.Label
        h_v                   matlab.ui.control.EditField
        DensityRokgm3Label    matlab.ui.control.Label
        rho_v                 matlab.ui.control.EditField
        INPUTLabel            matlab.ui.control.Label
        FINDWHATLabel         matlab.ui.control.Label
        THERMODYNAMICPROPERTIESOFWATERANDSTEAMLabel  matlab.ui.control.Label
        PhaseDropDownLabel    matlab.ui.control.Label
        phase                 matlab.ui.control.DropDown
        clear                 matlab.ui.control.Button
        VapourfractionXLabel  matlab.ui.control.Label
        x_v                   matlab.ui.control.EditField
        clp                   matlab.ui.control.Button
        clx                   matlab.ui.control.Button
        clt                   matlab.ui.control.Button
        cls                   matlab.ui.control.Button
        clh                   matlab.ui.control.Button
        clrho                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: START
        function STARTButtonPushed(app, event)
            %------------------Temperature--------------------------
            if string(app.can_tim.Value)=='Temperature (°C)'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('Tsat_p',str2double(app.p_v.Value)));
                   elseif (app.h_v.Value~=""&&app.p_v.Value~=""&&app.s_v.Value=="")
                           app.xuat.Value=num2str(XSteam('T_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif (app.h_v.Value~=""&&app.s_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('T_hs',str2double(app.h_v.Value),str2double(app.s_v.Value)));
                   elseif (app.p_v.Value~=""&&app.s_v.Value~=""&&app.h_v.Value=="")
                           app.xuat.Value=num2str(XSteam('T_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %------------------Pressure-----------------------------
            if (string(app.can_tim.Value)=='Pressure (bar)')
                if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.t_v.Value~=""&&app.rho_v.Value=="")
                        app.xuat.Value=num2str(XSteam('psat_T',str2double(app.t_v.Value)));
                elseif (app.h_v.Value~=""&&app.s_v.Value~=""&&app.t_v.Value==""&&app.rho_v.Value=="")
                        app.xuat.Value=num2str(XSteam('p_hs',str2double(app.h_v.Value),str2double(app.s_v.Value)));
                elseif (app.h_v.Value~=""&&app.rho_v.Value~=""&&app.t_v.Value==""&&app.s_v.Value=="")
                        app.xuat.Value=num2str(XSteam('p_hrho',str2double(app.h_v.Value),str2double(app.rho_v.Value)));             
                end
            end
            %-------------------Enthalpy------------------------
            if string(app.can_tim.Value)=='Enthalpy (kJ/kg)'&&string(app.phase.Value)=='Vapour'
                   if     (app.p_v.Value~=""&&app.t_v.Value==""&&app.s_v.Value==""&&app.x_v.Value==""&&app.rho_v.Value=="")
                           app.xuat.Value=num2str(XSteam('hV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value==""&&app.s_v.Value==""&&app.x_v.Value==""&&app.rho_v.Value=="")
                           app.xuat.Value=num2str(XSteam('hV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Enthalpy (kJ/kg)'&&string(app.phase.Value)=='Liquid'
                   if     (app.p_v.Value~=""&&app.t_v.Value==""&&app.s_v.Value==""&&app.x_v.Value==""&&app.rho_v.Value=="")
                           app.xuat.Value=num2str(XSteam('hL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value==""&&app.s_v.Value==""&&app.x_v.Value==""&&app.rho_v.Value=="")
                           app.xuat.Value=num2str(XSteam('hL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Enthalpy (kJ/kg)'&&string(app.phase.Value)=='Unknown'
                   if     ( app.s_v.Value==""&&app.x_v.Value==""&&app.rho_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('h_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.x_v.Value==""&&app.rho_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('h_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   elseif ( app.s_v.Value==""&&app.t_v.Value==""&&app.rho_v.Value==""&&app.p_v.Value~=""&&app.x_v.Value~="")
                           app.xuat.Value=num2str(XSteam('h_px',str2double(app.p_v.Value),str2double(app.x_v.Value)));
                   elseif ( app.s_v.Value==""&&app.x_v.Value==""&&app.t_v.Value==""&&app.p_v.Value~=""&&app.rho_v.Value~="")
                           app.xuat.Value=num2str(XSteam('h_prho',str2double(app.p_v.Value),str2double(app.rho_v.Value)));
                   elseif ( app.s_v.Value==""&&app.p_v.Value==""&&app.rho_v.Value==""&&app.t_v.Value~=""&&app.x_v.Value~="")
                           app.xuat.Value=num2str(XSteam('h_Tx',str2double(app.t_v.Value),str2double(app.x_v.Value)));
                   end
            end
            %-------------------Volume------------------------
            if string(app.can_tim.Value)=='Specific volume (m3/kg)'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('vV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('vV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific volume (m3/kg)'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('vL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('vL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific volume (m3/kg)'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('v_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('v_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.h_v.Value==""&&app.t_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('v_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %-------------------Density------------------------
            if string(app.can_tim.Value)=='Density (kg/m3)'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('rhoV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('rhoV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Density (kg/m3)'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('rhoL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('rhoL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Density (kg/m3)'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('rho_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('rho_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.h_v.Value==""&&app.t_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('rho_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %-------------------Entropy------------------------
            if string(app.can_tim.Value)=='Specific entropy (kJ/(kg°C))'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('sV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('sV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific entropy (kJ/(kg°C))'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('sL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('sL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific entropy (kJ/(kg°C))'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('s_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('s_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   end
            end
            %-------------------Specific internal energy------------------------
            if string(app.can_tim.Value)=='Specific internal energy (kJ/kg)'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('uV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('uV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific internal energy (kJ/kg)'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('uL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('uL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific internal energy (kJ/kg)'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('u_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('u_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.h_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('u_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %-------------------Specific isobaric heat capacity------------------------
            if string(app.can_tim.Value)=='Specific isobaric heat capacity (kJ/(kg°C))'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('CpV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('CpV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific isobaric heat capacity (kJ/(kg°C))'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('CpL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('CpL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific isobaric heat capacity (kJ/(kg°C))'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('Cp_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('Cp_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.h_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('Cp_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %-------------------Specific isochoric heat capacity------------------------
            if string(app.can_tim.Value)=='Specific isochoric heat capacity (kJ/(kg°C))'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('CvV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('CvV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific isochoric heat capacity (kJ/(kg°C))'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('CvL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('CvL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Specific isochoric heat capacity (kJ/(kg°C))'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('Cv_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('Cv_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.h_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('Cv_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %-------------------Speed of sound------------------------
            if string(app.can_tim.Value)=='Speed of sound (m/s)'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('wV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('wV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Speed of sound (m/s)'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('wL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('wL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Speed of sound (m/s)'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('w_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('w_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.h_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('w_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
             %-------------------Thermal conductivity------------------------
            if string(app.can_tim.Value)=='Thermal Conductivity (W/(m°C))'&&string(app.phase.Value)=='Vapour'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('tcV_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('tcV_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Thermal Conductivity (W/(m°C))'&&string(app.phase.Value)=='Liquid'
                   if     (app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('tcL_p',str2double(app.p_v.Value)));
                   elseif (app.t_v.Value~=""&&app.p_v.Value=="")
                           app.xuat.Value=num2str(XSteam('tcL_T',str2double(app.t_v.Value)));
                   end
            end
            if string(app.can_tim.Value)=='Thermal Conductivity (W/(m°C))'&&string(app.phase.Value)=='Unknown'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('tc_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('tc_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.p_v.Value==""&&app.h_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('tc_hs',str2double(app.h_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %----------------------Viscosity---------------------------------
            if string(app.can_tim.Value)=='Viscosity (Pa.s)'
                   if     ( app.h_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('my_pT',str2double(app.p_v.Value),str2double(app.t_v.Value)));
                   elseif ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('my_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.h_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('my_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %----------------------Vapour fraction---------------------------------
            if string(app.can_tim.Value)=='Vapour fraction'
                   if     ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('x_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.h_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('x_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
            %----------------------Vapour Volume fraction---------------------------------
            if string(app.can_tim.Value)=='Vapour volume fraction'
                   if     ( app.t_v.Value==""&&app.s_v.Value==""&&app.p_v.Value~=""&&app.h_v.Value~="")
                           app.xuat.Value=num2str(XSteam('vx_ph',str2double(app.p_v.Value),str2double(app.h_v.Value)));
                   elseif ( app.t_v.Value==""&&app.h_v.Value==""&&app.p_v.Value~=""&&app.s_v.Value~="")
                           app.xuat.Value=num2str(XSteam('vx_ps',str2double(app.p_v.Value),str2double(app.s_v.Value)));
                   end
            end
             %------------------Surface tension--------------------------
            if string(app.can_tim.Value)=='Surface Tension (N/m)'
                   if     ( app.t_v.Value==""&&app.p_v.Value~="")
                           app.xuat.Value=num2str(XSteam('st_p',str2double(app.p_v.Value)));
                   elseif ( app.p_v.Value==""&&app.t_v.Value~="")
                           app.xuat.Value=num2str(XSteam('st_T',str2double(app.t_v.Value)));
                   end
            end
        end

        % Button pushed function: clear
        function clearButtonPushed(app, event)
            app.xuat.Value='';
            app.p_v.Value='';
            app.t_v.Value='';
            app.s_v.Value='';
            app.h_v.Value='';
            app.rho_v.Value='';
            app.x_v.Value='';
            
        end

        % Button pushed function: clp
        function clpButtonPushed(app, event)
            app.p_v.Value="";
        end

        % Button pushed function: clt
        function cltButtonPushed(app, event)
            app.t_v.Value="";
        end

        % Button pushed function: clh
        function clhButtonPushed(app, event)
            app.h_v.Value="";
        end

        % Button pushed function: cls
        function clsButtonPushed(app, event)
            app.s_v.Value="";
        end

        % Button pushed function: clrho
        function clrhoButtonPushed(app, event)
            app.rho_v.Value="";
        end

        % Button pushed function: clx
        function clxButtonPushed(app, event)
            app.x_v.Value="";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermodynamicpropertiesofWaterandSteamUIFigure = uifigure('Visible', 'off');
            app.ThermodynamicpropertiesofWaterandSteamUIFigure.Name = 'Thermodynamic properties of Water and Steam';
            app.START = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.START.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.START.Text = 'FIND';
            app.xuat = uieditfield(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'text');
            app.can_tim = uidropdown(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.can_tim.Items = {'Temperature (°C)', 'Thermal Conductivity (W/(m°C))', 'Pressure (bar)', 'Density (kg/m3)', 'Enthalpy (kJ/kg)', 'Specific Cacity  (kJ/(kg°C))', 'Specific volume (m3/kg)', 'Specific entropy (kJ/(kg°C))', 'Specific internal energy (kJ/kg)', 'Specific isobaric heat capacity (kJ/(kg°C))', 'Specific isochoric heat capacity (kJ/(kg°C))', 'Surface Tension (N/m)', 'Speed of sound (m/s)', 'Viscosity (Pa.s)', 'Vapour fraction', 'Vapour volume fraction'};
            app.can_tim.Value = 'Temperature (°C)';
            app.PressurePbarLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.PressurePbarLabel.Text = 'Pressure (P) (bar)';
            app.p_v = uieditfield(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'text');
            app.TemperatureTCLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.TemperatureTCLabel.Text = 'Temperature (T) (°C)';
            app.t_v = uieditfield(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'text');
            app.EntropySkJkgCLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.EntropySkJkgCLabel.Text = 'Entropy (S) (kJ/(kg °C)) ';
            app.s_v = uieditfield(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'text');
            app.EnthalpyHkJkgLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.EnthalpyHkJkgLabel.Text = 'Enthalpy (H) (kJ/kg)';
            app.h_v = uieditfield(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'text');
            app.DensityRokgm3Label = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.DensityRokgm3Label.Text = 'Density (Ro) (kg/ m3)';
            app.rho_v = uieditfield(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'text');
            app.INPUTLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.INPUTLabel.Text = 'INPUT  ';
            app.FINDWHATLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.FINDWHATLabel.Text = 'FIND WHAT?';
            app.THERMODYNAMICPROPERTIESOFWATERANDSTEAMLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.THERMODYNAMICPROPERTIESOFWATERANDSTEAMLabel.Text = 'THERMODYNAMIC PROPERTIES OF WATER AND STEAM';
            app.PhaseDropDownLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.phase = uidropdown(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.phase.Items = {'Vapour', 'Liquid', 'Unknown'};
            app.phase.Value = 'Unknown';
            app.clear = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clear.Text = 'CLEAR ALL';
            app.VapourfractionXLabel = uilabel(app.ThermodynamicpropertiesofWaterandSteamUIFigure);
            app.VapourfractionXLabel.Text = 'Vapour fraction (X)';
            app.x_v = uieditfield(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'text');
            app.clp = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.clp.ButtonPushedFcn = createCallbackFcn(app, @clpButtonPushed, true);
            app.clp.Text = 'Clear';
            app.clx = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.clx.ButtonPushedFcn = createCallbackFcn(app, @clxButtonPushed, true);
            app.clx.Text = 'Clear';
            app.clt = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.clt.ButtonPushedFcn = createCallbackFcn(app, @cltButtonPushed, true);
            app.clt.Text = 'Clear';
            app.cls = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.cls.ButtonPushedFcn = createCallbackFcn(app, @clsButtonPushed, true);
            app.cls.Text = 'Clear';
            app.clh = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.clh.ButtonPushedFcn = createCallbackFcn(app, @clhButtonPushed, true);
            app.clh.Text = 'Clear';
            app.clrho = uibutton(app.ThermodynamicpropertiesofWaterandSteamUIFigure, 'push');
            app.clrho.ButtonPushedFcn = createCallbackFcn(app, @clrhoButtonPushed, true);
            app.clrho.Text = 'Clear';
            % Show the figure after all components are created
            app.ThermodynamicpropertiesofWaterandSteamUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRA_THONG_SO_NUOC_V1_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermodynamicpropertiesofWaterandSteamUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermodynamicpropertiesofWaterandSteamUIFigure)
        end
    end
end